classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalculatethedifferentialentropyhXLabel  matlab.ui.control.Label
        VariableEditFieldLabel        matlab.ui.control.Label
        VariableEditField             matlab.ui.control.EditField
        pdfEditFieldLabel             matlab.ui.control.Label
        pdfEditField                  matlab.ui.control.EditField
        aEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        bEditField                    matlab.ui.control.NumericEditField
        NormalizationfactorEditFieldLabel  matlab.ui.control.Label
        NormalizationfactorEditField  matlab.ui.control.EditField
        CalculateButton               matlab.ui.control.Button
        hXEditFieldLabel              matlab.ui.control.Label
        hXEditField                   matlab.ui.control.NumericEditField
        UpperboundEditFieldLabel      matlab.ui.control.Label
        UpperboundEditField           matlab.ui.control.NumericEditField
        LabelError                    matlab.ui.control.Label
    end


    methods (Access = private)
        
        % result of failed sanity checks displayed
        function setError(app, message)
            app.LabelError.Text = message;
        end
        
    end


    methods (Access = private)

        % Button pushed function: CalculateButton
        function calculate(app, event)
            check_ok = true;
            setError(app,''); % data clening in case of multiple usages
            
            % 1: retrieving user's input
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            
            % 1.1 sanity checks on the input
            if a > b
                check_ok = false;
                setError(app,'a must be lower or equal than b');
            end
            try
                f = str2func(strcat('@(',app.VariableEditField.Value,')',app.pdfEditField.Value));
            catch 
                check_ok = false;
                setError(app,'Not able to convert the specified PDF, check the variable name and the pdf.');
            end
            % input is valid
            if check_ok
                app.LabelError.Text = '';
                
                % 2: normalization factor for the pdf (formula from assignment)
                normalization_factor = int(sym(app.pdfEditField.Value), [a,b]);
                app.NormalizationfactorEditField.Value = char(normalization_factor); % normalization factor output
                
                % 3: differential entropy of the normalized f
                try
                    f_normalized = f/normalization_factor;
                catch 
                    setError(app,'Variable name and pdf might be not matching.');
                    return
                end
                
                % h(X) = integral(f(x) * log(f(x)) dx
                expr = f_normalized * log2(f_normalized);
                % sanity check
                if ~isnan(expr)
                    hX = -int(sym(expr), [a,b]);
                    hX_ = double(hX);
                    app.hXEditField.Value = hX_; % entropy output
                    drawnow;
                    
                    % 4- Gaussian UB
                    % UB = h(g) = integral(f(x) * log(g(x)) dx
                    % shortcut formula from slides (see report)
                    
                    expr =@(x) x * f_normalized;
                    mu = int(sym(expr), [a,b]);
                    mu_ = double(mu);
                    
                    expr =@(x) x^2 * f_normalized;
                    mu2 = int(sym(expr), [a,b]);
                    mu2_ = double(mu2);
                    
                    varian = mu2_ - (mu_^2);
                    hg = 1/2 * log2(2*pi*exp(1)*varian);
                    app.UpperboundEditField.Value = hg; % gaussian UB output
                else
                     setError(app,'The inserted pdf is not valid.');
                end
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.CalculatethedifferentialentropyhXLabel = uilabel(app.UIFigure);
            app.CalculatethedifferentialentropyhXLabel.Text = 'Calculate the differential entropy h(X)';
            app.VariableEditFieldLabel = uilabel(app.UIFigure);
            app.VariableEditFieldLabel.Text = 'Variable:';
            app.VariableEditField = uieditfield(app.UIFigure, 'text');
            app.VariableEditField.Value = 'x';
            app.pdfEditFieldLabel = uilabel(app.UIFigure);
            app.pdfEditFieldLabel.Text = 'pdf:';
            app.pdfEditField = uieditfield(app.UIFigure, 'text');
            app.pdfEditField.Value = '1/(1+x^4)';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a:';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = -Inf;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = Inf;
            app.NormalizationfactorEditFieldLabel = uilabel(app.UIFigure);
            app.NormalizationfactorEditFieldLabel.Text = 'Normalization factor:';
            app.NormalizationfactorEditField = uieditfield(app.UIFigure, 'text');
            app.NormalizationfactorEditField.Editable = 'off';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.CalculateButton.Text = 'Calculate';
            app.hXEditFieldLabel = uilabel(app.UIFigure);
            app.hXEditFieldLabel.Text = 'h(X):';
            app.hXEditField = uieditfield(app.UIFigure, 'numeric');
            app.hXEditField.Editable = 'off';
            app.UpperboundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperboundEditFieldLabel.Text = 'Upper bound:';
            app.UpperboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperboundEditField.Editable = 'off';
            app.LabelError = uilabel(app.UIFigure);
            app.LabelError.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end