classdef App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RangeXEditFieldLabel        matlab.ui.control.Label
        RangeXEditField             matlab.ui.control.EditField
        RangeYEditFieldLabel        matlab.ui.control.Label
        RangeYEditField             matlab.ui.control.EditField
        RangeZEditFieldLabel        matlab.ui.control.Label
        RangeZEditField             matlab.ui.control.EditField
        ProbDistrXLabel             matlab.ui.control.Label
        ProbDistrXEditField         matlab.ui.control.EditField
        ProbDistrYLabel             matlab.ui.control.Label
        ProbDistrYEditField         matlab.ui.control.EditField
        ProbDistrZLabel             matlab.ui.control.Label
        ProbDistrZEditField         matlab.ui.control.EditField
        fxyzLabel                   matlab.ui.control.Label
        fxyzEditField               matlab.ui.control.EditField
        ComputeButton               matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        ErrorsLabel                 matlab.ui.control.Label
        EntropyXEditFieldLabel      matlab.ui.control.Label
        EntropyXEditField           matlab.ui.control.EditField
        EntropyYEditFieldLabel      matlab.ui.control.Label
        EntropyYEditField           matlab.ui.control.EditField
        EntropyZEditFieldLabel      matlab.ui.control.Label
        EntropyZEditField           matlab.ui.control.EditField
        ENTROPYfXYZEditFieldLabel   matlab.ui.control.Label
        ENTROPYfXYZEditField        matlab.ui.control.EditField
        InputLabel                  matlab.ui.control.Label
        OutputLabel                 matlab.ui.control.Label
        InequalitiesLabel           matlab.ui.control.Label
        AlltheentropiesarepositiveLabel  matlab.ui.control.Label
        PositiveEntropiesEditField  matlab.ui.control.EditField
        Entropiesupperboundedbylog2cardinalityrvLabel  matlab.ui.control.Label
        UBEntropiesEditField        matlab.ui.control.EditField
        EntropyofafunctioninequalityLabel  matlab.ui.control.Label
        FunctionEntropyEditField    matlab.ui.control.EditField
        CheckthereportinordertoknowmoreabouttheinequalitiesLabel  matlab.ui.control.Label
    end


    methods (Access = private)
    
        function setError(app, text)
            app.ErrorsLabel.Text = text;
        end
        
        
        function h = H(app,p)
            p(p==0) = [];
            h       = -sum(p.*log2(p));
        end
        
%        function r = jointInequality(app,X1, X2, px1, px2)
%            hx1 = H(app,px1);
%            hx2 = H(app,px2);
%            
%            % derive px1x2
%            px1x2 = [];
%            for i = 1:numel(X1)
%                 for j = 1:numel(X2)
%                     px1x2 = [px1x2 px1(i)*px2(j)];
%                 end
%            end
%            hx1x2 = H(app,px1x2);
%            if hx1 < hx1x2 && hx2 < hx1x2 && round(hx1x2,2) <= round((hx1+hx2),2)
%                r = true;
%            else
%                r = false;
%            end
%        end
%        
%        function r = conditionalInequality(app,X1, X2, px1, px2)
%            hx1 = H(app,px1);
%            hx2 = H(app,px2);
%            
%            % derive px1x2
%            px1x2 = [];
%            for i = 1:numel(X1)
%                 for j = 1:numel(X2)
%                     px1x2 = [px1x2 px1(i)*px2(j)];
%                 end
%            end
%            hx1x2 = H(app,px1x2);
%            
%            ix1x2 = hx1 + hx2 - hx1x2; % = 0 if independent
%            hx1_conditional_x2 = hx1 - ix1x2;
%            hx2_conditional_x1 = hx2 - ix1x2;
%            
%            if round(hx1_conditional_x2,2) <= round(hx1,2) && round(hx2_conditional_x1,2) <= round(hx2,2)
%                r = true;
%            else
%                r = false;
%            end
%        end
        
    end


    methods (Access = private)

        % Button pushed function: ComputeButton
        function compute(app, event)
            setError(app, '');
            
            % 1-retrieving user input
            X = str2num(app.RangeXEditField.Value);
            Y = str2num(app.RangeYEditField.Value);
            Z = str2num(app.RangeZEditField.Value);
            
            px = str2num(app.ProbDistrXEditField.Value);
            py = str2num(app.ProbDistrYEditField.Value);
            pz = str2num(app.ProbDistrZEditField.Value);
            
            fxyz = str2func( ['@(x,y,z) ', app.fxyzEditField.Value ]);
            
            % 2-sanity checks
            % a- probability distributions consistent with rv's
            check_ok = true;
            if (numel(X) ~= numel(px))
                setError(app, 'X range is not consistent with X probability distr');
                drawnow;
                check_ok = false;
            end
            if (numel(Y) ~= numel(py))
                setError(app, 'Y range is not consistent with Y probability distr');
                drawnow;
                check_ok = false;
            end
            if (numel(Z) ~= numel(pz))
                setError(app, 'Z range is not consistent with Z probability distr');
                drawnow;
                check_ok = false;
            end
            
            if check_ok
                % normalization factors
                normalization_factor_px = sum(px);
                normalization_factor_py = sum(py);
                normalization_factor_pz = sum(pz);
                
                % 3-normalized probability vectors
                px = px/normalization_factor_px;
                py = py/normalization_factor_py;
                pz = pz/normalization_factor_pz;
                
                % 4- calculate entropies
                hx = H(app, px);
                hy = H(app, py);
                hz = H(app, pz);
                
                % entropy of f(X,Y,Z)
                syms f(x,y,z)
                try
                    f(x,y,z) = fxyz; 
                catch
                    setError(app, 'f(x,y,z) is not consistent.');
                    return;
                end
                range_f_xyz = zeros(1, numel(X)*numel(Y)*numel(Z));
                cnt = 1;
                for i = 1:numel(X)
                    for j = 1:numel(Y)
                        for w = 1:numel(Z)
                            aus = (f(X(i), Y(j), Z(w)));
                            range_f_xyz(cnt) = aus;
                            cnt = cnt+1;
                        end
                    end
                end
                uv = unique(range_f_xyz);
                n  = histc(range_f_xyz,uv); % frequency of each element
                pxyz = n/sum(n); % probability of each element
                hxyz = H(app,pxyz);
                
                
                % entropies output
                app.EntropyXEditField.Value = num2str(hx);
                app.EntropyYEditField.Value = num2str(hy);
                app.EntropyZEditField.Value = num2str(hz);
                app.ENTROPYfXYZEditField.Value = num2str(hxyz);
                
                % ------ INEQUALITIES ---------
                % - a) all entropies must be positive (in the case of DISCRETE rv's)
                if (hx > 0 && hy > 0 && hz > 0 && hxyz > 0)
                    positivity = 'Verified';
                else
                    positivity = 'NOT Verified';
                end
                
                % - b) all the entropies are upperbounded by log2(N)
                if hx <= log2(numel(X)) && hy <= log2(numel(Y)) && hz <= log2(numel(Z))
                    entropies_upperbound = 'Verified';
                else
                    entropies_upperbound = 'NOT Verified';
                end
                
                % - c)H(x1) <= H(x1,x2) = H(x2,x1)
                % - d)H(x1,x2) <= H(x1) + H(x2)
            %                 ji1 = jointInequality(app,X, Y, px, py);
            %                 ji2 = jointInequality(app,X, Z, px, pz);
            %                 ji3 = jointInequality(app,Y, Z, py, pz);
            %                 
            %                 if ji1 && ji2 && ji3
            %                     joint = 'Verified';
            %                 else
            %                     joint = 'NOT Verified';
            %                 end
                
                % - e)H(x1,x2,x3) >= H(f(x1,x2,x3) entropy of a function
                px1x2x3 = [];
                for i = 1:numel(X)
                    for j = 1:numel(Y)
                        for w = 1:numel(Z)
                            px1x2x3 = [pxyz px(i)*py(j)*pz(w)];
                        end
                    end
                end
                hx1x2x3 = H(app,px1x2x3);
                if hx1x2x3 >= hxyz
                    entropy_of_a_function = 'Verified';
                else
                    entropy_of_a_function = 'NOT Verified';
                end
                
                % f - conditional entropies
                % H(x|y) = H(x) - I(x;y) <= H(x)
                % where I(x;y) = H(x) + H(y) - H(x,y)
            %                 ci1 = conditionalInequality(app, X, Y, px, py);
            %                 ci2 = conditionalInequality(app,X, Z, px, pz);
            %                 ci3 = conditionalInequality(app,Y, Z, py, pz);
            %                 
            %                 if ci1 && ci2 && ci3
            %                     conditional = 'Verified';
            %                 else
            %                     conditional = 'NOT Verified';
            %                 end
                
                % output - inequalities
                app.PositiveEntropiesEditField.Value = positivity;
                
                app.UBEntropiesEditField.Value = entropies_upperbound;
                app.FunctionEntropyEditField.Value = entropy_of_a_function;
            %                 app.ConditionalEntropiesEditField.Value = conditional;
            %                 app.JointEntropiesEditField.Value = joint;
             end
               
        end

        % Button pushed function: ExitButton
        function exit(app, event)
            app.delete;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.RangeXEditFieldLabel = uilabel(app.UIFigure);
            app.RangeXEditFieldLabel.Text = 'Range X:';
            app.RangeXEditField = uieditfield(app.UIFigure, 'text');
            app.RangeXEditField.Value = '1:10';
            app.RangeYEditFieldLabel = uilabel(app.UIFigure);
            app.RangeYEditFieldLabel.Text = 'Range Y:';
            app.RangeYEditField = uieditfield(app.UIFigure, 'text');
            app.RangeYEditField.Value = '[1:5,8:10]';
            app.RangeZEditFieldLabel = uilabel(app.UIFigure);
            app.RangeZEditFieldLabel.Text = 'Range Z:';
            app.RangeZEditField = uieditfield(app.UIFigure, 'text');
            app.RangeZEditField.Value = '1:10';
            app.ProbDistrXLabel = uilabel(app.UIFigure);
            app.ProbDistrXLabel.Text = 'Prob. Distr. X:';
            app.ProbDistrXEditField = uieditfield(app.UIFigure, 'text');
            app.ProbDistrXEditField.Value = 'ones(1,10)';
            app.ProbDistrYLabel = uilabel(app.UIFigure);
            app.ProbDistrYLabel.Text = 'Prob. Distr. Y:';
            app.ProbDistrYEditField = uieditfield(app.UIFigure, 'text');
            app.ProbDistrYEditField.Value = 'ones(1,8)';
            app.ProbDistrZLabel = uilabel(app.UIFigure);
            app.ProbDistrZLabel.Text = 'Prob. Distr. Z:';
            app.ProbDistrZEditField = uieditfield(app.UIFigure, 'text');
            app.ProbDistrZEditField.Value = 'ones(1,10)';
            app.fxyzLabel = uilabel(app.UIFigure);
            app.fxyzLabel.Text = 'f(x,y,z):';
            app.fxyzEditField = uieditfield(app.UIFigure, 'text');
            app.fxyzEditField.Value = 'x+y*z';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @compute, true);
            app.ComputeButton.Text = 'Compute';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @exit, true);
            app.ExitButton.Text = 'Exit';
            app.ErrorsLabel = uilabel(app.UIFigure);
            app.ErrorsLabel.Text = 'Note: the execution takes a few seconds owing to the interpretation of f(x,y,z)';
            app.EntropyXEditFieldLabel = uilabel(app.UIFigure);
            app.EntropyXEditFieldLabel.Text = 'Entropy X:';
            app.EntropyXEditField = uieditfield(app.UIFigure, 'text');
            app.EntropyXEditField.Editable = 'off';
            app.EntropyYEditFieldLabel = uilabel(app.UIFigure);
            app.EntropyYEditFieldLabel.Text = 'Entropy Y:';
            app.EntropyYEditField = uieditfield(app.UIFigure, 'text');
            app.EntropyYEditField.Editable = 'off';
            app.EntropyZEditFieldLabel = uilabel(app.UIFigure);
            app.EntropyZEditFieldLabel.Text = 'Entropy Z:';
            app.EntropyZEditField = uieditfield(app.UIFigure, 'text');
            app.EntropyZEditField.Editable = 'off';
            app.ENTROPYfXYZEditFieldLabel = uilabel(app.UIFigure);
            app.ENTROPYfXYZEditFieldLabel.Text = 'ENTROPY f(X,Y,Z):';
            app.ENTROPYfXYZEditField = uieditfield(app.UIFigure, 'text');
            app.ENTROPYfXYZEditField.Editable = 'off';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.InequalitiesLabel = uilabel(app.UIFigure);
            app.InequalitiesLabel.Text = 'Inequalities';
            app.AlltheentropiesarepositiveLabel = uilabel(app.UIFigure);
            app.AlltheentropiesarepositiveLabel.Text = 'All the entropies are positive:';
            app.PositiveEntropiesEditField = uieditfield(app.UIFigure, 'text');
            app.PositiveEntropiesEditField.Editable = 'off';
            app.Entropiesupperboundedbylog2cardinalityrvLabel = uilabel(app.UIFigure);
            app.Entropiesupperboundedbylog2cardinalityrvLabel.Text = 'Entropies upper bounded by log2(cardinality rv):';
            app.UBEntropiesEditField = uieditfield(app.UIFigure, 'text');
            app.UBEntropiesEditField.Editable = 'off';
            app.EntropyofafunctioninequalityLabel = uilabel(app.UIFigure);
            app.EntropyofafunctioninequalityLabel.Text = 'Entropy of a function inequality:';
            app.FunctionEntropyEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEntropyEditField.Editable = 'off';
            app.CheckthereportinordertoknowmoreabouttheinequalitiesLabel = uilabel(app.UIFigure);
            app.CheckthereportinordertoknowmoreabouttheinequalitiesLabel.Text = 'Check the report in order to know more about the inequalities';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end