classdef App3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AlphabetEditFieldLabel        matlab.ui.control.Label
        AlphabetEditField             matlab.ui.control.EditField
        ProbabilityvectorEditFieldLabel  matlab.ui.control.Label
        ProbabilityvectorEditField    matlab.ui.control.EditField
        StringtoEncodeEditFieldLabel  matlab.ui.control.Label
        StringtoEncodeEditField       matlab.ui.control.EditField
        ENCODEButton                  matlab.ui.control.Button
        ErrorsLabel                   matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ExitButton                    matlab.ui.control.Button
        EncodedvectorEditFieldLabel   matlab.ui.control.Label
        EncodedvectorEditField        matlab.ui.control.EditField
        Label_2                       matlab.ui.control.Label
        NotetheexecutionmighttakeafewsecondsincaseoflongstringsLabel  matlab.ui.control.Label
    end


    methods (Access = private)
    
        function setError(app,text)
            app.ErrorsLabel.Text = text;
            drawnow
        end     
        
        function d2b = binarize(app,a)
            n = 0;         % number bits for integer part of your number      
            m = 64;         % number bits for fraction part of your number
            % binary number
            d2b = fix(rem(a*pow2(-(n-1):m),2)); 
        end   
    
        function [output,interval] = renormalization(app,l_,u_)
            l = binarize(app,l_);
            u = binarize(app,u_);
            new_l = [];
            new_u = [];
            output = [];
            head = true;
            coda_l = [];
            coda_u = [];
            for i = 1:numel(l)
                if l(i) == u(i) && head
                    coda_l = [coda_l 0];
                    coda_u = [coda_u 1];
                    output = [output l(i)];
                else
                    new_l = [new_l l(i)];
                    new_u = [new_u u(i)];
                    head = false;
                end
            end
            new_l = [new_l coda_l];
            new_u = [new_u coda_u];
            interval = [new_l new_u];
            n = 0;
            m = 64;
            l_b2d = new_l*pow2([n-1:-1:0 -(1:m)].');
            u_b2d = new_u*pow2([n-1:-1:0 -(1:m)].');
            interval = [l_b2d, u_b2d];
        end
        
        
        function Tag_bits = float2bin(app,tag, l)
            % Embedding the Final Tag Value.
            Tag_bits=zeros(1,0);
            bits=zeros(1,0);
            if(2*tag>1)
                tag=2*tag-1;
                bits=[bits,'1'];
            else
                tag=2*tag;
                bits=[bits,'0'];
            end
            while (bin2dec(bits)/2^length(bits)) < l
                if(2*tag>1)
                    tag=2*tag-1;
                    bits=[bits,'1'];
                else
                    tag=2*tag;
                    bits=[bits,'0'];
                end
            end
            Tag_bits=[Tag_bits,bits];
        end
        
        % Dictionary, {key: value} => {element: [L U]}, [L U] interval for element 
        % NB i ALWAYS consider whitespace
        function [d,dictOutput] = createDictionary(app,p_vec, alphabet, l, dictOutput)            
            d = containers.Map;
            u = 0;
            for idx_el = 1:numel(alphabet)
                char_el = alphabet(idx_el);
                u = l + p_vec(idx_el);
                if ~isKey(d,char_el)
                    [output, interval] = renormalization(app,l,u);
                    d(char_el)  = interval;
                    dictOutput(char_el) = [dictOutput(char_el) output];
                end
                l = u;
            end
            [output, interval] = renormalization(app,u,u+p_vec(numel(p_vec)));
            d('whitespace') = interval; %i suppose the whitespace is the last element of probability vector
            dictOutput('whitespace') = [dictOutput('whitespace') output]; 
        end
        
        function d = createOutputDictionary(app, alphabet)            
            d = containers.Map; 
            for idx_el = 1:numel(alphabet)
                char_el = alphabet(idx_el);
                if ~isKey(d,char_el)
                    d(char_el)  = [];
                end
            end
            d('whitespace') = []; %i suppose the whitespace is the last element of probability vector
        end
    end


    methods (Access = private)

        % Button pushed function: ENCODEButton
        function encode(app, event)
            setError(app,'');
            
            %1: retrieve user's input
            % check is alphabet is unique
            alphabet = lower(app.AlphabetEditField.Value);
            alphabet = unique(alphabet);
            try
                p_vec = str2num(app.ProbabilityvectorEditField.Value);
            catch 
                setError(app,'ERROR: conversion of probability vector');
                return;
            end
            str_to_encode = lower(app.StringtoEncodeEditField.Value);
            %2: check if probability vector is normalized, if not normalize it
            if sum(p_vec) ~= 1
                s = sum(p_vec);
                p_vec = p_vec/s; %normalized pvec
            end
            
            %3: sanity checks
            % 1-alphabet should map string to encode
            check_ok = true;
            unique_str_to_encode = unique(str_to_encode);
            unique_str_to_encode= unique_str_to_encode(find(~isspace(unique_str_to_encode)));
            for i = 1:numel(unique_str_to_encode)
                if ~contains(alphabet, unique_str_to_encode(i))
                    check_ok = false;
                    setError(app,'ERROR: alphabet does not map string to encode');
                end
            end
            % 2-probability vector size must be equal to alphabet size
            if numel(p_vec) ~= length(alphabet)+1
                check_ok = false;
                setError(app,'ERROR: probability vector is not consistent with alphabet (pvec should be 1 element more than the alphabet => whitespace)');
            end
            if check_ok
                % artithmetic coding
    
                % assign each symbol to an interval range from 0 (inclusive) to 1 (exclusive).
                % based on its probability.
                
                %STEP0
                % Let's suppose we have A,B,C. P(A) = 0.1, P(B) = 0.5, P(C) = 0.4
                % A will have the assigned interval [0, 0.1), B [0.1, 0.6), C [0.6 1)
                % initial probability dictionary
                
           
                dictOutput =createOutputDictionary(app, alphabet);
                [dict,dictOutput] = createDictionary(app,p_vec, alphabet,0,dictOutput);
                p_vec_upd = p_vec;
                
                %STEP1
                % We're intersted ONLY in the final interval
                % At each iteration we 'normalize' the probability vector
                % and narrow the interval
                for idx_el = 1:numel(str_to_encode)
                    char_el = str_to_encode(idx_el);
                    if (char_el == ' ')  %
                        char_el = 'whitespace';
                    end
                    interval_chosen = dict(char_el);
                    output_chosen = dictOutput(char_el);
                    
                    l = interval_chosen(1);
                    u = interval_chosen(2);
                    if idx_el < numel(str_to_encode)
                        amplitude_interval_chosen = u - l;
                        p_vec_upd = p_vec * amplitude_interval_chosen;
                        [dict,dictOutput] = createDictionary(app,p_vec_upd, alphabet, l, dictOutput);
                    end
                    
                end
                final_interval = interval_chosen;
                % by definition each value in the final interval it's ok (mind the
                % brackets)  -> i choose the mean
                
                final_float = (mean(interval_chosen));
                final_bit_sequence = float2bin(app,final_float, l);
                
                result = [output_chosen char2num(final_bit_sequence)];
                
                % output
                % encoded bit sequence
                app.EncodedvectorEditField.Value = char(result);
                drawnow;
                
                % result label
                string_to_encode_len = length(str_to_encode);
                final_bit_sequence_len = length(num2str(final_bit_sequence));
                ratio = final_bit_sequence_len/string_to_encode_len;
                app.Label.Text = sprintf('Input string of %d chars encoded by %d bits [%.3f bits/char]',string_to_encode_len,final_bit_sequence_len, ratio);
            end
        end

        % Button pushed function: ExitButton
        function exit(app, event)
            app.delete;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.AlphabetEditFieldLabel = uilabel(app.UIFigure);
            app.AlphabetEditFieldLabel.Text = 'Alphabet:';
            app.AlphabetEditField = uieditfield(app.UIFigure, 'text');
            app.AlphabetEditField.Value = 'abcde';
            app.ProbabilityvectorEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilityvectorEditFieldLabel.Text = 'Probability vector:';
            app.ProbabilityvectorEditField = uieditfield(app.UIFigure, 'text');
            app.ProbabilityvectorEditField.Value = 'ones(1,6)';
            app.StringtoEncodeEditFieldLabel = uilabel(app.UIFigure);
            app.StringtoEncodeEditFieldLabel.Text = 'String to Encode';
            app.StringtoEncodeEditField = uieditfield(app.UIFigure, 'text');
            app.StringtoEncodeEditField.Value = 'abba';
            app.ENCODEButton = uibutton(app.UIFigure, 'push');
            app.ENCODEButton.ButtonPushedFcn = createCallbackFcn(app, @encode, true);
            app.ENCODEButton.Text = 'ENCODE';
            app.ErrorsLabel = uilabel(app.UIFigure);
            app.ErrorsLabel.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @exit, true);
            app.ExitButton.Text = 'Exit';
            app.EncodedvectorEditFieldLabel = uilabel(app.UIFigure);
            app.EncodedvectorEditFieldLabel.Text = 'Encoded vector:';
            app.EncodedvectorEditField = uieditfield(app.UIFigure, 'text');
            app.EncodedvectorEditField.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'The probability of the whitespace is the last in the probability vector, that has to be cardinality(alphabet)+1 elements';
            app.NotetheexecutionmighttakeafewsecondsincaseoflongstringsLabel = uilabel(app.UIFigure);
            app.NotetheexecutionmighttakeafewsecondsincaseoflongstringsLabel.Text = 'Note: the execution might take a few seconds in case of long strings';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App3()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end